classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        PlotButton                   matlab.ui.control.Button
        basalMetabolicRateEditField  matlab.ui.control.NumericEditField
        basalMetabolicRateEditFieldLabel  matlab.ui.control.Label
        typeDropDown                 matlab.ui.control.DropDown
        typeDropDownLabel            matlab.ui.control.Label
        wingAspectEditField          matlab.ui.control.NumericEditField
        wingAspectLabel              matlab.ui.control.Label
        wingSpanmEditField           matlab.ui.control.NumericEditField
        wingSpanmEditFieldLabel      matlab.ui.control.Label
        masskgEditField              matlab.ui.control.NumericEditField
        masskgEditFieldLabel         matlab.ui.control.Label
        DropDownBird                 matlab.ui.control.DropDown
        SpeciesLabel                 matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            birdList = readtable("bird.csv");
            app.DropDownBird.Items = birdList.commonName;
        end

        % Value changed function: DropDownBird
        function DropDownBirdValueChanged(app, event)
            bird = Bird(app.DropDownBird.Value);
            app.masskgEditField.Value                 = bird.mass;
            app.wingSpanmEditField.Value             = bird.wingSpan;
            app.wingAspectEditField.Value           = bird.wingAspect;
            app.typeDropDown.Value                  = bird.type;
            app.basalMetabolicRateEditField.Value   = bird.basalMetabolicRate;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            mass                    = app.masskgEditField.Value;
            wingSpan                = app.wingSpanmEditField.Value;
            wingAspect              = app.wingAspectEditField.Value;
            type                    = app.typeDropDown.Value;
            bird                    = Bird(mass,wingSpan,wingAspect,type);
            bird.basalMetabolicRate = app.basalMetabolicRateEditField.Value;
            
            % Compute mechanical power
            P = mechanicalPower(bird);
            
            % Solve speed
            syms V

            dPdV = diff(P,V);
            Vmp = double(solve(dPdV==0,V,'PrincipalValue',true));
            Vmr = double(solve(dPdV==P/V,V,'PrincipalValue',true, 'MaxDegree', 10,'IgnoreAnalyticConstraints',true));
            Vmt = double(solve(dPdV==(P+bird.basalMetabolicRate)/V,V,'PrincipalValue',true, 'MaxDegree', 10,'IgnoreAnalyticConstraints',true));
            
            xinterval=[3 ceil(Vmt)];
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            % Vmp
            fplot(app.UIAxes,P,xinterval,'LineWidth',2)
            scatter(app.UIAxes,Vmp,double(subs(P,Vmp)),'o','filled')
            text(app.UIAxes,Vmp,0.1+double(subs(P,Vmp)),'V_{mp}')
            yline(app.UIAxes,double(subs(P,Vmp)))
            % Vmr
            scatter(app.UIAxes,Vmr,double(subs(P,Vmr)),'o','filled')
            text(app.UIAxes,Vmr-.9,0.1+double(subs(P,Vmr)),'V_{mr}')
            fplot(app.UIAxes,V*double(subs(P,Vmr))/Vmr,[-1 20])
            % Vmt
            scatter(app.UIAxes,Vmt,double(subs(P,Vmt)),'o','filled')
            text(app.UIAxes,Vmt-.9,0.1+double(subs(P,Vmt)),'V_{mt}')
            fplot(app.UIAxes,V*double(subs(P,Vmt)+bird.basalMetabolicRate)/Vmt-bird.basalMetabolicRate,[-1 20])
            plot(app.UIAxes,[0 0],[0 -bird.basalMetabolicRate])
            axis tight;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {380, 380};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpeciesLabel = uilabel(app.LeftPanel);
            app.SpeciesLabel.Text = 'Species';
            app.DropDownBird = uidropdown(app.LeftPanel);
            app.DropDownBird.Items = {};
            app.DropDownBird.ValueChangedFcn = createCallbackFcn(app, @DropDownBirdValueChanged, true);
            app.DropDownBird.Value = {};
            app.masskgEditFieldLabel = uilabel(app.LeftPanel);
            app.masskgEditFieldLabel.Text = 'mass [kg]';
            app.masskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.wingSpanmEditFieldLabel = uilabel(app.LeftPanel);
            app.wingSpanmEditFieldLabel.Text = 'wingSpan [m]';
            app.wingSpanmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.wingAspectLabel = uilabel(app.LeftPanel);
            app.wingAspectLabel.Text = 'wingAspect [-]';
            app.wingAspectEditField = uieditfield(app.LeftPanel, 'numeric');
            app.typeDropDownLabel = uilabel(app.LeftPanel);
            app.typeDropDownLabel.Text = 'type';
            app.typeDropDown = uidropdown(app.LeftPanel);
            app.typeDropDown.Items = {'passerine', 'waterfowl', 'wader', 'tern', 'seabird', 'bat', 'unknown'};
            app.typeDropDown.Value = 'passerine';
            app.basalMetabolicRateEditFieldLabel = uilabel(app.LeftPanel);
            app.basalMetabolicRateEditFieldLabel.Text = 'basalMetabolicRate';
            app.basalMetabolicRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Power curve')
            xlabel(app.UIAxes, 'True airspeed [m/s]')
            ylabel(app.UIAxes, 'Power [W=J/s]')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end